classdef CapabilityCurveVisiualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        ParametersPanel                 matlab.ui.container.Panel
        PMWEditFieldLabel               matlab.ui.control.Label
        PMWEditField                    matlab.ui.control.NumericEditField
        QMvarEditFieldLabel             matlab.ui.control.Label
        QMvarEditField                  matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel       matlab.ui.control.Label
        PowerFactorEditField            matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        OperatingconditionTab           matlab.ui.container.Tab
        OverexcitedLampLabel            matlab.ui.control.Label
        OverexcitedLamp                 matlab.ui.control.Lamp
        UnderexcitedLampLabel           matlab.ui.control.Label
        UnderexcitedLamp                matlab.ui.control.Lamp
        FieldcurrentAEditFieldLabel     matlab.ui.control.Label
        FieldcurrentAEditField          matlab.ui.control.NumericEditField
        ArmaturecurrentAEditFieldLabel  matlab.ui.control.Label
        ArmaturecurrentAEditField       matlab.ui.control.NumericEditField
        CheckconditionTab               matlab.ui.container.Tab
        PpuEditFieldLabel               matlab.ui.control.Label
        PpuEditField                    matlab.ui.control.NumericEditField
        QpuEditFieldLabel               matlab.ui.control.Label
        QpuEditField                    matlab.ui.control.NumericEditField
        OverexcitedLamp_2Label          matlab.ui.control.Label
        OverexcitedLamp_2               matlab.ui.control.Lamp
        UnderexcitedLamp_2Label         matlab.ui.control.Label
        UnderexcitedLamp_2              matlab.ui.control.Lamp
        MachineParametersTab            matlab.ui.container.Tab
        RatedMVAEditFieldLabel          matlab.ui.control.Label
        RatedMVAEditField               matlab.ui.control.NumericEditField
        RatedpowerfactorLabel           matlab.ui.control.Label
        RatedpowerfactorEditField       matlab.ui.control.NumericEditField
        SynchronousreactanceXdpuEditFieldLabel  matlab.ui.control.Label
        SynchronousreactanceXdpuEditField  matlab.ui.control.NumericEditField
        RatedFieldcurrentAEditFieldLabel  matlab.ui.control.Label
        RatedFieldcurrentAEditField     matlab.ui.control.NumericEditField
        LimitcheckerLampLabel           matlab.ui.control.Label
        LimitcheckerLamp                matlab.ui.control.Lamp
        StartButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        EditField                       matlab.ui.control.EditField
        UIAxes3                         matlab.ui.control.UIAxes
        RotortempCEditFieldLabel        matlab.ui.control.Label
        RotortempCEditField             matlab.ui.control.NumericEditField
        StatortempCEditFieldLabel       matlab.ui.control.Label
        StatortempCEditField            matlab.ui.control.NumericEditField
        TraceSwitchLabel                matlab.ui.control.Label
        TraceSwitch                     matlab.ui.control.Switch
    end

    methods (Access = private)

        % Callback function: OperatingconditionTab, PMWEditField, 
        % PpuEditField, QMvarEditField, QpuEditField, 
        % RatedMVAEditField, StartButton, 
        % SynchronousreactanceXdpuEditField
        function StartButtonPushed(app, event)
       

%% Configuration
Kundur_CIGRE_based_collapse_04 % load the data
output_data1 = output_data(1:100,2:end); % eliminate time column

%% Data separation

V = output_data1(:,1); % bus voltage, volts
P1 = output_data1(:,2); % active power, [-]
Q1 = output_data1(:,3); % reactive power, [-]
Sbase=app.RatedMVAEditField.Value; %1600MVA
Ppu =(100/Sbase)*P1;
Qpu= (100/Sbase)*Q1;
time=output_data(:,1);
%%Plot
% Plot of both limits at rated MVA=1 p.u, Voltage =1 p.u. and armature current =1 p.u
psi = acos(0.72); % limiting power factor angle.. for S=1600 MVA and P=1154MW cosphi= 0.72
V1=1;  % rated terminal voltage on its own base of 13.8 KV
Ia1=1; % rated armature current
Xd=app.SynchronousreactanceXdpuEditField.Value; % direct axis synchronous reactance for the machine 2.07
E_1 = sqrt((V1+Xd*Ia1*sin(psi))^2+(Xd*Ia1*cos(psi))^2);  %magnitude of emf vector 
R=(E_1*V1)/Xd;
P_1= 0:.01:0.72; % range of angles
Q_1 = (-1/Xd) + sqrt(R^2-P_1.^2);

%%Armature current plot settings
theta=0:0.001:pi;
Qx=1*cos(theta);
Py=1*sin(theta);

%Practical stability limit settings
y=0:0.1:0.9;
r=y+0.1;
x=(sqrt(r.^2-y.^2))-V1^2/Xd;
t = 1:numel(x);
xy = [x;y];
pp = spline(t,xy);
tInterp = linspace(1,numel(x));
xyInterp = ppval(pp, tInterp);


%Check condition paramter settings
P_2=app.PpuEditField.Value;
Q_2=app.QpuEditField.Value;
%%plot
for i=1:length(V)
    toggleValue = app.TraceSwitch.Value;
      if ( strcmpi(toggleValue,'on') )
        
        plot(app.UIAxes,Qx,Py,...%Armature current limit plot
        Qpu(1:i),Ppu(1:i), Qpu(i),Ppu(i),'*r',...%Operating point
        [-1 1],[0.72 0.72],...%turbine limit
        [0 0],[0 1],'k',[-1 1],[0 0],'k',...%axes
        [0 Qpu(i)],[0 Ppu(i)],...%vector length of operating point from origin
        Q_1,P_1,...%plot of field current limit
        [-V1^2/Xd Qpu(i)],[0 Ppu(i)],...%vector length of operating point from negative axis
        -V1^2/Xd,0,'*',...
        [-V1^2/Xd -V1^2/Xd],[0 1],'--k',...%theoretical stability limit;
        x,y,xyInterp(1,:),xyInterp(2,:));...   %practical stability limit
        %Q_2,P_2,'*k');                           %check condition plot;
 
      else
        plot(app.UIAxes,Qx,Py,...%Armature current limit plot
        Qpu(i),Ppu(i),'*r',...%Operating point
        [-1 1],[0.72 0.72],...%turbine limit
        [0 0],[0 1],'k',[-1 1],[0 0],'k',...%axes
        [0 Qpu(i)],[0 Ppu(i)],...%vector length of operating point from origin
        Q_1,P_1,...%plot of field current limit
        [-V1^2/Xd Qpu(i)],[0 Ppu(i)],...%vector length of operating point from negative axis
        -V1^2/Xd,0,'*',...
        [-V1^2/Xd -V1^2/Xd],[0 1],'--k',...%theoretical stability limit;
        x,y,xyInterp(1,:),xyInterp(2,:));...   %practical stability limit
       % Q_2,P_2,'*k');                           %check condition plot;
        
       end

    
        app.PMWEditField.Value=Ppu(i)*Sbase;
        app.QMvarEditField.Value=Qpu(i)*Sbase;
        app.PowerFactorEditField.Value=Ppu(i);


        %%Power Plot
        z=plot(app.UIAxes2,time(i),Ppu(i)*Sbase,'.',time(1:i),Ppu(1:i)*Sbase,... 
            time(i),Qpu(i)*Sbase,'.', time(1:i),Qpu(1:i)*Sbase);
        legend(app.UIAxes2,[z(2) z(4)],'MW','Mvar');
        
        %%Armature and field current
         X = [-V1^2/Xd,0;Qpu(i),Ppu(i)]; %vector length of operating point from negative x axis
         app.FieldcurrentAEditField.Value = pdist(X,'euclidean')*1065;

         Y= [0,0;Qpu(i),Ppu(i)]; %vector length of operating point from origin
         app.ArmaturecurrentAEditField.Value= pdist(Y,'euclidean')*5406;    % rated  terminal current is 66939A
         
%          X1(i)=pdist(X,'euclidean');
%          Y1(i)= pdist(Y,'euclidean');
         
         %% temperature plot, real time ....slow process
         
%          I_fd=[time(i) transpose(X1(i))*5539];
%          I_t=[time(i) transpose(Y1(i))*66939];
%          assignin('base','I_fd',I_fd);         % this brings the variable value into workspace that will be later used by model from workspace
%          assignin('base','I_t',I_t);
%          simOut=sim('Thermal_model_scope','TimeOut',300);
%          p=plot(app.UIAxes3,simOut.T_r.Time(i),simOut.T_r.Data(i),'.',simOut.T_r.Time(1:i),simOut.T_r.Data(1:i),...
%                simOut.T_s.Time(i),simOut.T_s.Data(i),'.',simOut.T_s.Time(1:i),simOut.T_s.Data(1:i));
%          legend(app.UIAxes3,p,'Tr','Ts');
         
         %temperature plot directly from matfile that conatins variable simOut
         load('RotorTemperature.mat');
         p=plot(app.UIAxes3,simOut.T_r.Time(i),simOut.T_r.Data(i),'.',simOut.T_r.Time(1:i),simOut.T_r.Data(1:i),...
               simOut.T_s.Time(i),simOut.T_s.Data(i),'.',simOut.T_s.Time(1:i),simOut.T_s.Data(1:i));
         legend(app.UIAxes3,[p(2) p(4)],'Tr','Ts');
         
          app.RotortempCEditField.Value=simOut.T_r.Data(i);
          app.StatortempCEditField.Value=simOut.T_s.Data(i);
         %if Q reactive power is positive overexcited 
          if Qpu(i)>0   
              app.OverexcitedLamp.Color='green';
              app.UnderexcitedLamp.Color='black';
          else
              app.OverexcitedLamp.Color='black';
              app.UnderexcitedLamp.Color='green';
          end
          
          %over excitation and underexcitation check  
          if app.QpuEditField.Value>0          
              app.OverexcitedLamp_2.Color='green';
              app.UnderexcitedLamp_2.Color='black';
          else
              app.OverexcitedLamp_2.Color='black';
              app.UnderexcitedLamp_2.Color='green';
          end
          
          %limit checker for turbine limit only
          if Ppu(i)>0.73            
            app.LimitcheckerLamp.Color='Red';
            app.EditField.Value='ALARM';
        else
            app.LimitcheckerLamp.Color='green';
            app.EditField.Value='NORMAL';
          end
          
          pause(1e-30);
end
           %% plots at last after completing for loop, no real time plot: 
%          I_fd=[time transpose(X1)*5539];
%          I_t =[time transpose(Y1)*66939];
%          assignin('base','I_fd',I_fd);
%          assignin('base','I_t',I_t);
%          simOut=sim('Thermal_model_scope','TimeOut',300);
%          p=plot(app.UIAxes3,simOut.T_r.Time,simOut.T_r.Data,...
%                simOut.T_s.Time,simOut.T_s.Data);
%          legend(app.UIAxes3,p,'Tr','Ts');
        end

        % Callback function
        function PathPlotValueChanged(app, event)
            value = app.PathPlot.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Capability Curve')
            xlabel(app.UIAxes, 'Reactive Power Q [p.u.]')
            ylabel(app.UIAxes, 'Active Power P [p.u.]')
            app.UIAxes.XTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.PMWEditFieldLabel = uilabel(app.ParametersPanel);
            app.PMWEditFieldLabel.Text = 'P [MW]';
            app.PMWEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PMWEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.QMvarEditFieldLabel = uilabel(app.ParametersPanel);
            app.QMvarEditFieldLabel.Text = 'Q [Mvar]';
            app.QMvarEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.QMvarEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PowerFactorEditFieldLabel = uilabel(app.ParametersPanel);
            app.PowerFactorEditFieldLabel.Text = 'Power Factor';
            app.PowerFactorEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OperatingconditionTab = uitab(app.TabGroup);
            app.OperatingconditionTab.SizeChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.OperatingconditionTab.Title = 'Operating condition';
            app.OverexcitedLampLabel = uilabel(app.OperatingconditionTab);
            app.OverexcitedLampLabel.Text = 'Overexcited';
            app.OverexcitedLamp = uilamp(app.OperatingconditionTab);
            app.UnderexcitedLampLabel = uilabel(app.OperatingconditionTab);
            app.UnderexcitedLampLabel.Text = 'Underexcited';
            app.UnderexcitedLamp = uilamp(app.OperatingconditionTab);
            app.FieldcurrentAEditFieldLabel = uilabel(app.OperatingconditionTab);
            app.FieldcurrentAEditFieldLabel.Text = 'Field current [A]';
            app.FieldcurrentAEditField = uieditfield(app.OperatingconditionTab, 'numeric');
            app.ArmaturecurrentAEditFieldLabel = uilabel(app.OperatingconditionTab);
            app.ArmaturecurrentAEditFieldLabel.Text = 'Armature current [A]';
            app.ArmaturecurrentAEditField = uieditfield(app.OperatingconditionTab, 'numeric');
            app.CheckconditionTab = uitab(app.TabGroup);
            app.CheckconditionTab.Title = 'Check condition';
            app.PpuEditFieldLabel = uilabel(app.CheckconditionTab);
            app.PpuEditFieldLabel.Text = 'P [p.u.]';
            app.PpuEditField = uieditfield(app.CheckconditionTab, 'numeric');
            app.PpuEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.PpuEditField.Value = 0.5;
            app.QpuEditFieldLabel = uilabel(app.CheckconditionTab);
            app.QpuEditFieldLabel.Text = 'Q [p.u.]';
            app.QpuEditField = uieditfield(app.CheckconditionTab, 'numeric');
            app.QpuEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.QpuEditField.Value = 0.7;
            app.OverexcitedLamp_2Label = uilabel(app.CheckconditionTab);
            app.OverexcitedLamp_2Label.Text = 'Overexcited';
            app.OverexcitedLamp_2 = uilamp(app.CheckconditionTab);
            app.UnderexcitedLamp_2Label = uilabel(app.CheckconditionTab);
            app.UnderexcitedLamp_2Label.Text = 'Underexcited';
            app.UnderexcitedLamp_2 = uilamp(app.CheckconditionTab);
            app.MachineParametersTab = uitab(app.TabGroup);
            app.MachineParametersTab.Title = 'Machine Parameters';
            app.RatedMVAEditFieldLabel = uilabel(app.MachineParametersTab);
            app.RatedMVAEditFieldLabel.Text = 'Rated MVA';
            app.RatedMVAEditField = uieditfield(app.MachineParametersTab, 'numeric');
            app.RatedMVAEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.RatedMVAEditField.Value = 1600;
            app.RatedpowerfactorLabel = uilabel(app.MachineParametersTab);
            app.RatedpowerfactorLabel.Text = 'Rated power factor ';
            app.RatedpowerfactorEditField = uieditfield(app.MachineParametersTab, 'numeric');
            app.RatedpowerfactorEditField.Value = 0.72;
            app.SynchronousreactanceXdpuEditFieldLabel = uilabel(app.MachineParametersTab);
            app.SynchronousreactanceXdpuEditFieldLabel.Text = 'Synchronous reactance Xd [p.u.]';
            app.SynchronousreactanceXdpuEditField = uieditfield(app.MachineParametersTab, 'numeric');
            app.SynchronousreactanceXdpuEditField.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.SynchronousreactanceXdpuEditField.Value = 2.07;
            app.RatedFieldcurrentAEditFieldLabel = uilabel(app.MachineParametersTab);
            app.RatedFieldcurrentAEditFieldLabel.Text = 'Rated Field current [A]';
            app.RatedFieldcurrentAEditField = uieditfield(app.MachineParametersTab, 'numeric');
            app.RatedFieldcurrentAEditField.Value = 66939;
            app.LimitcheckerLampLabel = uilabel(app.UIFigure);
            app.LimitcheckerLampLabel.Text = 'Limit checker';
            app.LimitcheckerLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Active and Reactive Power')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Power ')
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'NORMAL';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Rotor and Stator Temperature')
            xlabel(app.UIAxes3, 'Time [s]')
            ylabel(app.UIAxes3, 'Temperature [°C]')
            app.RotortempCEditFieldLabel = uilabel(app.UIFigure);
            app.RotortempCEditFieldLabel.Text = 'Rotor temp. [°C]';
            app.RotortempCEditField = uieditfield(app.UIFigure, 'numeric');
            app.StatortempCEditFieldLabel = uilabel(app.UIFigure);
            app.StatortempCEditFieldLabel.Text = 'Stator temp. [°C]';
            app.StatortempCEditField = uieditfield(app.UIFigure, 'numeric');
            app.TraceSwitchLabel = uilabel(app.UIFigure);
            app.TraceSwitchLabel.Text = 'Trace';
            app.TraceSwitch = uiswitch(app.UIFigure, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = CapabilityCurveVisiualization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end